classdef Inverse_Solution_guide < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Inverse_Solutions_PlatformUIFigure  matlab.ui.Figure
        FileMenu                      matlab.ui.container.Menu
        CreatedatastructureMenu       matlab.ui.container.Menu
        DatapathMenu                  matlab.ui.container.Menu
        ExitMenu                      matlab.ui.container.Menu
        ToolsMenu                     matlab.ui.container.Menu
        RunIverseSolutionMethodsMenu  matlab.ui.container.Menu
        SingleSubjectMenu             matlab.ui.container.Menu
        DataBatchingMenu              matlab.ui.container.Menu
        HelpMenu                      matlab.ui.container.Menu
        TextArea                      matlab.ui.control.TextArea
        TabGroup                      matlab.ui.container.TabGroup
        BeamfomerTab                  matlab.ui.container.Tab
        BeamfomerPanel                matlab.ui.container.Panel
        DropDownLabel                 matlab.ui.control.Label
        DropDown                      matlab.ui.control.DropDown
        EditFieldLabel                matlab.ui.control.Label
        EditField                     matlab.ui.control.EditField
        EditField2Label               matlab.ui.control.Label
        EditField2                    matlab.ui.control.EditField
        BMATab                        matlab.ui.container.Tab
        BMAPanel                      matlab.ui.container.Panel
        eLORETATab                    matlab.ui.container.Tab
        eLORETAPanel                  matlab.ui.container.Panel
        ENETMMTab                     matlab.ui.container.Tab
        ENETMMPanel                   matlab.ui.container.Panel
        LASSOMMTab                    matlab.ui.container.Tab
        LASSOMMPanel                  matlab.ui.container.Panel
        ENETSSBLTab                   matlab.ui.container.Tab
        ENETSSBLPanel                 matlab.ui.container.Panel
        sLORETATab                    matlab.ui.container.Tab
        sLORETAPanel                  matlab.ui.container.Panel
        STTONNICATab                  matlab.ui.container.Tab
        STTONNICAPanel                matlab.ui.container.Panel
        MethodsLabel                  matlab.ui.control.Label
        MethodsDropDown               matlab.ui.control.DropDown
        AddButton                     matlab.ui.control.Button
        RunButton                     matlab.ui.control.Button
        DeleteButton                  matlab.ui.control.Button
        ModeDropDownLabel             matlab.ui.control.Label
        ModeDropDown                  matlab.ui.control.DropDown
        BandsDropDown                 matlab.ui.control.DropDown
    end

    
    properties (Access = private)
        Property % Description
    end
    
    properties (Access = public)
        single_subject % Description
        attributes  % Description
        methods_array
        data_path
    end
    
    methods (Access = private)
        
        function setPromptFcn(app,jTextArea,eventData,newPrompt)
            % Prevent overlapping reentry due to prompt replacement
            persistent inProgress
            if isempty(inProgress)
                inProgress = 1;  %#ok unused
            else
                return;
            end
            
            try
                % *** Prompt modification code goes here ***
                cwText = char(jTextArea.getText);
                app.TextArea.Value = cwText;
                % force prompt-change callback to fizzle-out...
                pause(0.02);
            catch
                % Never mind - ignore errors...
            end
            
            % Enable new callbacks now that the prompt has been modified
            inProgress = [];
            
        end  % setPromptFcn
        
        
        function results = cofigurate_in_parameters(app)
            guiHandle = freqresol_maxfreq_samplfreq_guide;
            
            disp('------Waitintg for frequency_bands------');
            uiwait(guiHandle.UIFigure);
            
            if(guiHandle.canceled)
                delete(guiHandle);
                return;
            else
                delete(guiHandle);
            end
            
            if(app.data_path == 0 )
                folder = uigetdir('tittle','Select the Data''s Folder');
                if(folder==0)
                    return;
                end
                app.data_path = folder;
                file_path = strcat('properties',filesep,'properties.xml');
                root_tab =  'properties';
                parameter_name = "data_path";
                
                change_xml_parameter(file_path,root_tab,[parameter_name],[app.data_path],cell(0,0));
            end
            
            if(app.BandsDropDown.Visible == true)
                guiHandle = frequency_bands_guide;
                if(app.BandsDropDown.Value == "Frequency Bands")
                    guiHandle.ByfrequencybandButton.Value = true;
                else
                    guiHandle.FrequencybinButton.Value = true;
                end
                disp('------Waitintg for frequency_bands------');
                uiwait(guiHandle.UIFigure);
                
                if(guiHandle.canceled)
                    delete(guiHandle);
                    return;
                else
                    delete(guiHandle);
                end
            else
                
            end
            % defining methods for run
            
            root_tab = 'methods';
            parameters_name = app.methods_array;
            parameters_value = ["null","null","null","null","null","null","null","null","null"];
            
            change_xml_parameter(file_path,root_tab,parameters_name,parameters_value,app.attributes);
            %
        end
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc;
            warning off;
            addpath('guide');
            addpath('icon');
            addpath('tools');
            addpath('functions');
            addpath('properties');
            
            set(app.BeamfomerPanel,'Visible','off');
            set(app.BMAPanel,'Visible','off');
            set(app.eLORETAPanel,'Visible','off');
            set(app.ENETMMPanel,'Visible','off');
            set(app.LASSOMMPanel,'Visible','off');            
            set(app.ENETSSBLPanel,'Visible','off');
            set(app.sLORETAPanel,'Visible','off');
            set(app.STTONNICAPanel,'Visible','off');
            
            app.attributes = cell(1,9);
            for i = 1 : 9
                app.attributes{1,i} = ["run","false"];
            end
            
            app.methods_array = ["beamfomer","bma","eloreta","enetmm","lassomm",...
                "enetssbl","sloreta","sttonnica"];
            
            app.data_path = 0;
            try
                jDesktop = com.mathworks.mde.desk.MLDesktop.getInstance;
                jCmdWin = jDesktop.getClient('Command Window');
                jTextArea = jCmdWin.getComponent(0).getViewport.getView;
                set(jTextArea,'CaretUpdateCallback',@app.setPromptFcn)
            catch
                warndlg('fatal error');
            end
        end

        % Menu selected function: DataBatchingMenu
        function DataBatchingMenuSelected(app, event)
            root_tab =  'properties';
            parameter_name = 'run_single_subject';
            parameter_value = 0;
            change_xml_parameter(strcat('properties',filesep,'properties.xml'),root_tab,parameter_name,parameter_value);
            Main;
            msgbox('Completed operation!!!','Info');
        end

        % Menu selected function: RunIverseSolutionMethodsMenu
        function RunIverseSolutionMethodsMenuSelected(app, event)
            
        end

        % Callback function
        function ButtonPushed(app, event)
            jDesktop = com.mathworks.mde.desk.MLDesktop.getInstance;
            jCmdWin = jDesktop.getClient('Command Window');
            jTextArea = jCmdWin.getComponent(0).getViewport.getView;
            cwText = char(jTextArea.getText);
            
            set(jTextArea,'CaretUpdateCallback',@myUpdateFcn)
            
        end

        % Menu selected function: ExitMenu
        function ExitMenuSelected(app, event)
            delete(app);
        end

        % Menu selected function: SingleSubjectMenu
        function SingleSubjectMenuSelected(app, event)
            
            root_tab =  'properties';
            parameter_name = 'run_single_subject';
            parameter_value = 1;
            change_xml_parameter(strcat('properties',filesep,'properties.xml'),root_tab,parameter_name,parameter_value);
            Main;
            msgbox('Completed operation!!!','Info');
        end

        % Button pushed function: DeleteButton
        function DeleteButtonPushed(app, event)
            
            
            method_selected = app.MethodsDropDown.Value;
            switch method_selected
                case 'Beamfomer'
                    set(app.BeamfomerPanel,'Visible','off');
                    app.attributes{1,1} = ["run","false"];
                case 'BMA'
                    set(app.BMAPanel,'Visible','off');
                    app.attributes{1,2} = ["run","false"];
                case 'eLORETA'
                    set(app.eLORETAPanel,'Visible','off');
                    app.attributes{1,3} = ["run","false"];
                case 'ENETMM'
                    set(app.ENETMMPanel,'Visible','off');
                    app.attributes{1,4} = ["run","false"];
                case 'LASSOMM'
                    set(app.LASSOMMPanel,'Visible','off');
                    app.attributes{1,5} = ["run","false"];                
                case 'ENETSSBL'
                    set(app.ENETSSBLPanel,'Visible','off');
                    app.attributes{1,7} = ["run","false"];
                case 'sLORETA'
                    set(app.sLORETAPanel,'Visible','off');
                    app.attributes{1,8} = ["run","false"];
                case 'STONICA'
                    set(app.STTONNICAPanel,'Visible','off');
                    app.attributes{1,9} = ["run","false"];
            end
            
        end

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
            method_selected = app.MethodsDropDown.Value;
            switch method_selected
                case 'Beamfomer'
                    set(app.BeamfomerPanel,'Visible','on');
                    set(app.TabGroup,'SelectedTab',app.BeamfomerTab);
                    app.attributes{1,1} = ["name",method_selected,"run","true"];
                case 'BMA'
                    set(app.BMAPanel,'Visible','on');
                    set(app.TabGroup,'SelectedTab',app.BMATab);
                    app.attributes{1,2} = ["name",method_selected,"run","true"];
                case 'eLORETA'
                    set(app.eLORETAPanel,'Visible','on');
                    set(app.TabGroup,'SelectedTab',app.eLORETATab);
                    app.attributes{1,3} = ["name",method_selected,"run","true"];
                case 'ENET-MM'
                    set(app.ENETMMPanel,'Visible','on');
                    set(app.TabGroup,'SelectedTab',app.ENETMMTab);
                    app.attributes{1,4} = ["name",method_selected,"run","true"];
                case 'LASSO-MM'
                    set(app.LASSOMMPanel,'Visible','on');
                    set(app.TabGroup,'SelectedTab',app.LASSOMMTab);
                    app.attributes{1,5} = ["name",method_selected,"run","true"];               
                case 'ENET-SSBL'
                    set(app.ENETSSBLPanel,'Visible','on');
                    set(app.TabGroup,'SelectedTab',app.ENETSSBLTab);
                    app.attributes{1,7} = ["name",method_selected,"run","true"];
                case 'sLORETA'
                    set(app.sLORETAPanel,'Visible','on');
                    set(app.TabGroup,'SelectedTab',app.sLORETATab);
                    app.attributes{1,8} = ["name",method_selected,"run","true"];
                case 'STTONNICA'
                    set(app.STTONNICAPanel,'Visible','on');
                    set(app.TabGroup,'SelectedTab',app.STTONNICATab);
                    app.attributes{1,9} = ["name",method_selected,"run","true"];
            end
        end

        % Value changed function: ModeDropDown
        function ModeDropDownValueChanged(app, event)
            value = app.ModeDropDown.Value;
            switch value
                case 'Time Domain'
                    set(app.BandsDropDown,'Visible','off');
                case 'Frequency Domain'
                    set(app.BandsDropDown,'Visible','on');
            end
            
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            % set_default_properties();
           
            %define configure parameters%            
            cofigurate_in_parameters();
            
            %Run salescted methods


             msgbox('Completed operation','Info');
        end

        % Value changed function: BandsDropDown
        function BandsDropDownValueChanged(app, event)
            value = app.BandsDropDown.Value;
            
        end

        % Menu selected function: DatapathMenu
        function DatapathMenuSelected(app, event)
            folder = uigetdir('tittle','Select the Source Folder');
            if(folder==0)
                return;
            end
            app.data_path = folder;
            file_path = strcat('properties',filesep,'properties.xml');
            root_tab =  'properties';
            parameter_name = "data_path";
            
            change_xml_parameter(file_path,root_tab,[parameter_name],[app.data_path],cell(0,0));
            
        end

        % Menu selected function: CreatedatastructureMenu
        function CreatedatastructureMenuSelected(app, event)
             folder = uigetdir('tittle','Select the Source Folder');
            if(folder==0)
                return;
            end
            create_data_structure(folder);
             msgbox('Completed operation','Info');
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Inverse_Solutions_PlatformUIFigure = uifigure;
            app.Inverse_Solutions_PlatformUIFigure.Name = 'Inverse Solutions Platform';
            app.FileMenu = uimenu(app.Inverse_Solutions_PlatformUIFigure);
            app.FileMenu.Text = 'File';
            app.CreatedatastructureMenu = uimenu(app.FileMenu);
            app.CreatedatastructureMenu.MenuSelectedFcn = createCallbackFcn(app, @CreatedatastructureMenuSelected, true);
            app.CreatedatastructureMenu.Text = 'Create data structure';
            app.DatapathMenu = uimenu(app.FileMenu);
            app.DatapathMenu.MenuSelectedFcn = createCallbackFcn(app, @DatapathMenuSelected, true);
            app.DatapathMenu.Text = 'Data path';
            app.ExitMenu = uimenu(app.FileMenu);
            app.ExitMenu.MenuSelectedFcn = createCallbackFcn(app, @ExitMenuSelected, true);
            app.ExitMenu.Text = 'Exit';
            app.ToolsMenu = uimenu(app.Inverse_Solutions_PlatformUIFigure);
            app.ToolsMenu.Text = 'Tools';
            app.RunIverseSolutionMethodsMenu = uimenu(app.ToolsMenu);
            app.RunIverseSolutionMethodsMenu.MenuSelectedFcn = createCallbackFcn(app, @RunIverseSolutionMethodsMenuSelected, true);
            app.RunIverseSolutionMethodsMenu.Text = 'Run Iverse Solution Methods';
            app.SingleSubjectMenu = uimenu(app.ToolsMenu);
            app.SingleSubjectMenu.MenuSelectedFcn = createCallbackFcn(app, @SingleSubjectMenuSelected, true);
            app.SingleSubjectMenu.Text = 'Single Subject';
            app.DataBatchingMenu = uimenu(app.ToolsMenu);
            app.DataBatchingMenu.MenuSelectedFcn = createCallbackFcn(app, @DataBatchingMenuSelected, true);
            app.DataBatchingMenu.Text = 'Data Batching';
            app.HelpMenu = uimenu(app.Inverse_Solutions_PlatformUIFigure);
            app.HelpMenu.Text = 'Help';
            app.TextArea = uitextarea(app.Inverse_Solutions_PlatformUIFigure);
            app.TabGroup = uitabgroup(app.Inverse_Solutions_PlatformUIFigure);
            app.BeamfomerTab = uitab(app.TabGroup);
            app.BeamfomerTab.Title = 'Beamfomer';
            app.BeamfomerPanel = uipanel(app.BeamfomerTab);
            app.DropDownLabel = uilabel(app.BeamfomerPanel);
            app.DropDownLabel.Text = 'Drop Down';
            app.DropDown = uidropdown(app.BeamfomerPanel);
            app.EditFieldLabel = uilabel(app.BeamfomerPanel);
            app.EditFieldLabel.Text = 'Edit Field';
            app.EditField = uieditfield(app.BeamfomerPanel, 'text');
            app.EditField2Label = uilabel(app.BeamfomerPanel);
            app.EditField2Label.Text = 'Edit Field2';
            app.EditField2 = uieditfield(app.BeamfomerPanel, 'text');
            app.BMATab = uitab(app.TabGroup);
            app.BMATab.Title = 'BMA';
            app.BMAPanel = uipanel(app.BMATab);
            app.eLORETATab = uitab(app.TabGroup);
            app.eLORETATab.Title = 'eLORETA';
            app.eLORETAPanel = uipanel(app.eLORETATab);
            app.ENETMMTab = uitab(app.TabGroup);
            app.ENETMMTab.Title = 'ENET-MM';
            app.ENETMMPanel = uipanel(app.ENETMMTab);
            app.LASSOMMTab = uitab(app.TabGroup);
            app.LASSOMMTab.Title = 'LASSO-MM';
            app.LASSOMMPanel = uipanel(app.LASSOMMTab);
            app.ENETSSBLTab = uitab(app.TabGroup);
            app.ENETSSBLTab.Title = 'ENET-SSBL';
            app.ENETSSBLPanel = uipanel(app.ENETSSBLTab);
            app.sLORETATab = uitab(app.TabGroup);
            app.sLORETATab.Title = 'sLORETA';
            app.sLORETAPanel = uipanel(app.sLORETATab);
            app.STTONNICATab = uitab(app.TabGroup);
            app.STTONNICATab.Title = 'STTONNICA';
            app.STTONNICAPanel = uipanel(app.STTONNICATab);
            app.MethodsLabel = uilabel(app.Inverse_Solutions_PlatformUIFigure);
            app.MethodsLabel.Text = 'Methods:';
            app.MethodsDropDown = uidropdown(app.Inverse_Solutions_PlatformUIFigure);
            app.MethodsDropDown.Items = {'Beamfomer', 'BMA', 'eLORETA', 'ENET-MM', 'LASSO-MM', 'ENET-SSBL', 'sLORETA', 'STTONNICA'};
            app.MethodsDropDown.Value = 'Beamfomer';
            app.AddButton = uibutton(app.Inverse_Solutions_PlatformUIFigure, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Icon = 'add.png';
            app.AddButton.Tooltip = {'Add method'};
            app.AddButton.Text = '';
            app.RunButton = uibutton(app.Inverse_Solutions_PlatformUIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Icon = 'run.png';
            app.RunButton.Tooltip = {'Run methods'};
            app.RunButton.Text = '';
            app.DeleteButton = uibutton(app.Inverse_Solutions_PlatformUIFigure, 'push');
            app.DeleteButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteButtonPushed, true);
            app.DeleteButton.Icon = 'delete.png';
            app.DeleteButton.Tooltip = {'Delete method'};
            app.DeleteButton.Text = '';
            app.ModeDropDownLabel = uilabel(app.Inverse_Solutions_PlatformUIFigure);
            app.ModeDropDownLabel.Text = 'Mode:';
            app.ModeDropDown = uidropdown(app.Inverse_Solutions_PlatformUIFigure);
            app.ModeDropDown.Items = {'Time Domain', 'Frequency Domain'};
            app.ModeDropDown.ValueChangedFcn = createCallbackFcn(app, @ModeDropDownValueChanged, true);
            app.ModeDropDown.Value = 'Time Domain';
            app.BandsDropDown = uidropdown(app.Inverse_Solutions_PlatformUIFigure);
            app.BandsDropDown.Items = {'Frequency Bands', 'Bin by bin'};
            app.BandsDropDown.ValueChangedFcn = createCallbackFcn(app, @BandsDropDownValueChanged, true);
            app.BandsDropDown.Visible = 'off';
            app.BandsDropDown.Value = 'Frequency Bands';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Inverse_Solution_guide

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Inverse_Solutions_PlatformUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Inverse_Solutions_PlatformUIFigure)
        end
    end
end