classdef frequency_bands_guide < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        SelectfrequencysbandPanel  matlab.ui.container.Panel
        CheckBox_Delta             matlab.ui.control.CheckBox
        CheckBox_theta             matlab.ui.control.CheckBox
        CheckBox_alpha             matlab.ui.control.CheckBox
        CheckBox_beta              matlab.ui.control.CheckBox
        FromLabel                  matlab.ui.control.Label
        HztoLabel                  matlab.ui.control.Label
        HzdeltaLabel               matlab.ui.control.Label
        Spinner_delta_start        matlab.ui.control.Spinner
        Spinner_delta_end          matlab.ui.control.Spinner
        FromLabel_2                matlab.ui.control.Label
        HzthetaLabel               matlab.ui.control.Label
        FromLabel_3                matlab.ui.control.Label
        HzalphaLabel               matlab.ui.control.Label
        FromLabel_4                matlab.ui.control.Label
        HzbetaLabel                matlab.ui.control.Label
        HztoLabel_2                matlab.ui.control.Label
        Spinner_theta_start        matlab.ui.control.Spinner
        Spinner_theta_end          matlab.ui.control.Spinner
        HztoLabel_3                matlab.ui.control.Label
        Spinner_alpha_start        matlab.ui.control.Spinner
        Spinner_alpha_end          matlab.ui.control.Spinner
        HztoLabel_4                matlab.ui.control.Label
        Spinner_beta_start         matlab.ui.control.Spinner
        Spinner_beta_end           matlab.ui.control.Spinner
        AgreeButton                matlab.ui.control.Button
        CancelButton               matlab.ui.control.Button
        RunmodeButtonGroup         matlab.ui.container.ButtonGroup
        ByfrequencybandButton      matlab.ui.control.RadioButton
        FrequencybinButton         matlab.ui.control.RadioButton
    end

    
    properties (Access = public)
        frequencies % Description
        Property2 % Description
        canceled
        frequency_bin
        mode
    end
    
    methods (Access = private)
        
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           
        end

        % Button pushed function: AgreeButton
        function AgreeButtonPushed(app, event)
            app.canceled = false;
            
            allOk = true;
            file_path = strcat('properties',filesep,'properties.xml');
            root_tab =  'properties';
            parameter_name = "run_frequency_bin";
            if(app.FrequencybinButton.Value == true)
                parameter_value = "1";
            else
                parameter_value = "0";
            end
            change_xml_parameter(file_path,root_tab,[parameter_name],[parameter_value],cell(0,0));
            
            parameters_name = ["delta_band","theta_band","alpha_band","beta_band"];
            parameters_value = ["null","null","null","null"];
            attributes =  cell(1,4);
            attributes{1,1} = ["end","4","run","false","start","0.1","name","delta"];
            attributes{1,2} = ["end","7","run","false","start","4","name","theta"];
            attributes{1,3} = ["end","14","run","false","start","7","name","alpha"];
            attributes{1,4} = ["end","31","run","false","start","14","name","beta"];


            if(app.CheckBox_Delta.Value)
                if(app.Spinner_delta_start.Value <= app.Spinner_delta_end.Value)
                    attributes{1,1} = ["end",app.Spinner_delta_end.Value,"run","true",...
                        "start",app.Spinner_delta_start.Value,"name","delta"];
                else
                    uiwait(msgbox('The start value to the delta band must be smaller than end value','Error','modal'));
                    allOk = false;
                end
            end
            if(app.CheckBox_theta.Value)
                if(app.Spinner_theta_start.Value <= app.Spinner_theta_end.Value)
                    attributes{1,2} = ["end",app.Spinner_theta_end.Value,"run","true",...
                        "start",app.Spinner_theta_start.Value,"name","theta"];
                else
                    uiwait(msgbox('The start value to the theta band must be smaller than end value','Error','modal'));
                    allOk = false;
                end
            end
            if(app.CheckBox_alpha.Value)
                if(app.Spinner_alpha_start.Value <= app.Spinner_alpha_end.Value)
                    attributes{1,3} = ["end",app.Spinner_alpha_end.Value,"run","true",...
                        "start",app.Spinner_alpha_start.Value,"name","alpha"];
                else
                    uiwait(msgbox('The start value to the alpha band must be smaller than end value','Error','modal'));
                    allOk = false;
                end
            end
            if(app.CheckBox_beta.Value)
                if(app.Spinner_beta_start.Value <= app.Spinner_beta_end.Value)
                    attributes{1,4} = ["end",app.Spinner_beta_end.Value,"run","true",...
                        "start",app.Spinner_beta_start.Value,"name","beta"];
                else
                    uiwait(msgbox('The start value to the beta band must be smaller than end value','Error','modal'));
                    allOk = false;
                end
            end
            if(allOk)
                % defining methods for run                  
                change_xml_parameter(file_path,root_tab,parameters_name,parameters_value,attributes);
                uiresume(app.UIFigure);
            end
            
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            app.canceled = true;
            uiresume(app.UIFigure);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.SelectfrequencysbandPanel = uipanel(app.UIFigure);
            app.SelectfrequencysbandPanel.Title = 'Select frequency''s band';
            app.CheckBox_Delta = uicheckbox(app.SelectfrequencysbandPanel);
            app.CheckBox_Delta.Text = '';
            app.CheckBox_Delta.Value = true;
            app.CheckBox_theta = uicheckbox(app.SelectfrequencysbandPanel);
            app.CheckBox_theta.Text = '';
            app.CheckBox_theta.Value = true;
            app.CheckBox_alpha = uicheckbox(app.SelectfrequencysbandPanel);
            app.CheckBox_alpha.Text = '';
            app.CheckBox_alpha.Value = true;
            app.CheckBox_beta = uicheckbox(app.SelectfrequencysbandPanel);
            app.CheckBox_beta.Text = '';
            app.CheckBox_beta.Value = true;
            app.FromLabel = uilabel(app.SelectfrequencysbandPanel);
            app.FromLabel.Text = 'From';
            app.HztoLabel = uilabel(app.SelectfrequencysbandPanel);
            app.HztoLabel.Text = ' Hz to ';
            app.HzdeltaLabel = uilabel(app.SelectfrequencysbandPanel);
            app.HzdeltaLabel.Text = 'Hz (delta)';
            app.Spinner_delta_start = uispinner(app.SelectfrequencysbandPanel);
            app.Spinner_delta_start.Step = 0.1;
            app.Spinner_delta_start.Limits = [0 50];
            app.Spinner_delta_start.Value = 0.1;
            app.Spinner_delta_end = uispinner(app.SelectfrequencysbandPanel);
            app.Spinner_delta_end.Step = 0.1;
            app.Spinner_delta_end.Limits = [0.1 50];
            app.Spinner_delta_end.Value = 4;
            app.FromLabel_2 = uilabel(app.SelectfrequencysbandPanel);
            app.FromLabel_2.Text = 'From';
            app.HzthetaLabel = uilabel(app.SelectfrequencysbandPanel);
            app.HzthetaLabel.Text = 'Hz (theta)';
            app.FromLabel_3 = uilabel(app.SelectfrequencysbandPanel);
            app.FromLabel_3.Text = 'From';
            app.HzalphaLabel = uilabel(app.SelectfrequencysbandPanel);
            app.HzalphaLabel.Text = 'Hz (alpha)';
            app.FromLabel_4 = uilabel(app.SelectfrequencysbandPanel);
            app.FromLabel_4.Text = 'From';
            app.HzbetaLabel = uilabel(app.SelectfrequencysbandPanel);
            app.HzbetaLabel.Text = 'Hz (beta)';
            app.HztoLabel_2 = uilabel(app.SelectfrequencysbandPanel);
            app.HztoLabel_2.Text = ' Hz to ';
            app.Spinner_theta_start = uispinner(app.SelectfrequencysbandPanel);
            app.Spinner_theta_start.Step = 0.1;
            app.Spinner_theta_start.Limits = [0 50];
            app.Spinner_theta_start.Value = 4;
            app.Spinner_theta_end = uispinner(app.SelectfrequencysbandPanel);
            app.Spinner_theta_end.Step = 0.1;
            app.Spinner_theta_end.Limits = [0.1 50];
            app.Spinner_theta_end.Value = 7;
            app.HztoLabel_3 = uilabel(app.SelectfrequencysbandPanel);
            app.HztoLabel_3.Text = ' Hz to ';
            app.Spinner_alpha_start = uispinner(app.SelectfrequencysbandPanel);
            app.Spinner_alpha_start.Step = 0.1;
            app.Spinner_alpha_start.Limits = [0 50];
            app.Spinner_alpha_start.Value = 7;
            app.Spinner_alpha_end = uispinner(app.SelectfrequencysbandPanel);
            app.Spinner_alpha_end.Step = 0.1;
            app.Spinner_alpha_end.Limits = [0.1 50];
            app.Spinner_alpha_end.Value = 14;
            app.HztoLabel_4 = uilabel(app.SelectfrequencysbandPanel);
            app.HztoLabel_4.Text = ' Hz to ';
            app.Spinner_beta_start = uispinner(app.SelectfrequencysbandPanel);
            app.Spinner_beta_start.Step = 0.1;
            app.Spinner_beta_start.Limits = [0 50];
            app.Spinner_beta_start.Value = 14;
            app.Spinner_beta_end = uispinner(app.SelectfrequencysbandPanel);
            app.Spinner_beta_end.Step = 0.1;
            app.Spinner_beta_end.Limits = [0.1 50];
            app.Spinner_beta_end.Value = 31;
            app.AgreeButton = uibutton(app.UIFigure, 'push');
            app.AgreeButton.ButtonPushedFcn = createCallbackFcn(app, @AgreeButtonPushed, true);
            app.AgreeButton.Text = 'Agree';
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.RunmodeButtonGroup = uibuttongroup(app.UIFigure);
            app.RunmodeButtonGroup.Title = 'Run mode';
            app.ByfrequencybandButton = uiradiobutton(app.RunmodeButtonGroup);
            app.ByfrequencybandButton.Text = 'By frequency band ';
            app.ByfrequencybandButton.Value = true;
            app.FrequencybinButton = uiradiobutton(app.RunmodeButtonGroup);
            app.FrequencybinButton.Text = 'Frequency bin';
        end
    end

    methods (Access = public)

        % Construct app
        function app = frequency_bands_guide

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end